classdef CIEExplorer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                        matlab.ui.Figure
		GridLayout                      matlab.ui.container.GridLayout
		GridLayout2                     matlab.ui.container.GridLayout
		ExitButton                      matlab.ui.control.Button
		CIEdatasetButtonGroup           matlab.ui.container.ButtonGroup
		cie_1964_10_degree_observer_CheckBox  matlab.ui.control.CheckBox
		cie_1931_2_degree_observer_CheckBox  matlab.ui.control.CheckBox
		cie_1931_1nm_observer_CheckBox  matlab.ui.control.CheckBox
		UIAxes                          matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			
		end

		% Button pushed function: ExitButton
		function ExitButtonPushed(app, event)
			app.delete();
		end

		% Button down function: CIEdatasetButtonGroup
		function CIEdatasetButtonGroupButtonDown(app, event)
			
		end

		% Value changed function: cie_1931_1nm_observer_CheckBox
		function cie_1931_1nm_observer_CheckBoxValueChanged(app, event)
			value = app.cie_1931_1nm_observer_CheckBox.Value;
			dataset = readtable("etc\CIE 1931 Standard Observer (1nm).xlsx");
			x = dataset{:,1} ./ (dataset{:,1} + dataset{:,2} + dataset{:,3});
			y = dataset{:,2} ./ (dataset{:,1} + dataset{:,2} + dataset{:,3});
			z = dataset{:,3} ./ (dataset{:,1} + dataset{:,2} + dataset{:,3});
			colors = [x, y, z];
			scatter3(app.UIAxes, dataset{:,1}, dataset{:,2}, dataset{:,3}, 5, colors, 'filled');
		end

		% Value changed function: cie_1931_2_degree_observer_CheckBox
		function cie_1931_2_degree_observer_CheckBoxValueChanged(app, event)
			value = app.cie_1931_2_degree_observer_CheckBox.Value;
			dataset = readtable("etc\CIE_cc_1931_2deg.csv");
			plot3(app.UIAxes, dataset{:,1}, dataset{:,2}, dataset{:,3});
			
		end

		% Value changed function: cie_1964_10_degree_observer_CheckBox
		function cie_1964_10_degree_observer_CheckBoxValueChanged(app, event)
			value = app.cie_1964_10_degree_observer_CheckBox.Value;
			dataset = readtable("etc\CIE_cc_1964_10deg.csv");
			plot3(app.UIAxes, dataset{:,1}, dataset{:,2}, dataset{:,3});
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.UIAxes = uiaxes(app.GridLayout);
			title(app.UIAxes, 'Title')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			app.GridLayout2 = uigridlayout(app.GridLayout);
			app.CIEdatasetButtonGroup = uibuttongroup(app.GridLayout2);
			app.CIEdatasetButtonGroup.Title = 'CIE data set';
			app.CIEdatasetButtonGroup.ButtonDownFcn = createCallbackFcn(app, @CIEdatasetButtonGroupButtonDown, true);
			app.cie_1931_1nm_observer_CheckBox = uicheckbox(app.CIEdatasetButtonGroup);
			app.cie_1931_1nm_observer_CheckBox.ValueChangedFcn = createCallbackFcn(app, @cie_1931_1nm_observer_CheckBoxValueChanged, true);
			app.cie_1931_1nm_observer_CheckBox.Text = '1931 1nm';
			app.cie_1931_2_degree_observer_CheckBox = uicheckbox(app.CIEdatasetButtonGroup);
			app.cie_1931_2_degree_observer_CheckBox.ValueChangedFcn = createCallbackFcn(app, @cie_1931_2_degree_observer_CheckBoxValueChanged, true);
			app.cie_1931_2_degree_observer_CheckBox.Text = '1931 2 degree';
			app.cie_1964_10_degree_observer_CheckBox = uicheckbox(app.CIEdatasetButtonGroup);
			app.cie_1964_10_degree_observer_CheckBox.ValueChangedFcn = createCallbackFcn(app, @cie_1964_10_degree_observer_CheckBoxValueChanged, true);
			app.cie_1964_10_degree_observer_CheckBox.Text = '1964 10 degree';
			app.ExitButton = uibutton(app.GridLayout2, 'push');
			app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
			app.ExitButton.Text = 'Exit';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = CIEExplorer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end