classdef ImageDeltaE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDeltaEVisualizationUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        RenderingIntentDropDown        matlab.ui.control.DropDown
        RenderingIntentDropDownLabel   matlab.ui.control.Label
        DisplayProfileButton           matlab.ui.control.Button
        DeltaEButton                   matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        DestinationImageDisplay        matlab.ui.control.Image
        SourceImageDisplay             matlab.ui.control.Image
        DestinationLabel               matlab.ui.control.Label
        SourceLabel                    matlab.ui.control.Label
        SourceImageandDeltaELabel      matlab.ui.control.Label
        SourceProfileButton            matlab.ui.control.Button
        DestinationProfileButton       matlab.ui.control.Button
        SourceImageButton              matlab.ui.control.Button
        DeltaEUIAxes                   matlab.ui.control.UIAxes
        DeltaEHistogramUIAxes          matlab.ui.control.UIAxes
        SourceImageHistogramAxes       matlab.ui.control.UIAxes
        DestinationImageHistogramAxes  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ImagePath % Full path to the selected image
        sourceImage   % the source image

        inputImageToC1     % the input image mapped to the input color space (C1)


        inputToMonitorImage     % the input image mapped to the monitor profile
        outputToMonitorImage    % the output image mapped to the monitor profile

        sourceProfile  % the profile of the input image
        sourceProfileFlag   % hack to allow us to see if the input profile has been got

        destinationImage    % the inputImage profiled using the destinationProfile
        destinationProfile    % the profile structure for destination rendering
        destinationProfileFlag   % hack to allow us to see if the output profile has been got

        sRGBProfile    % the default color space. This needs to be in the profiles directory.
        displayProfile = 'sRGB'

        dEImage     % the Delta E image

        inputCTransform    % the input image transform
        outputCTranform    % the output image transform
        inputCtoMonitorTransform   % the transform for input -> monitor
        outputCtoMonitorTransform  % the transform for output -> monitor
        inputImageHistogramAxisMax % the maximum value for the input image histogram axis
        outputImageHistogramAxisMax % the maximum value for the output image histogram axis
    end

    methods (Access = private)

        function refreshInputMappedImage(app)
            app.inputCtoMonitorTransform = makecform('icc', app.sourceProfile, app.displayProfile);
            app.inputToMonitorImage = applycform(app.sourceImage, app.inputCtoMonitorTransform);
            app.SourceImageDisplay.ImageSource = app.inputToMonitorImage;
            app.inputImageHistogramAxisMax = colorHistogram(app, app.inputToMonitorImage, app.SourceImageHistogramAxes);
        end

        function refreshOutputMappedImage(app)
            app.outputCtoMonitorTransform = makecform('icc', app.destinationProfile, app.displayProfile);
            app.outputToMonitorImage = applycform(app.sourceImage, app.outputCtoMonitorTransform);
            app.DestinationImageDisplay.ImageSource = app.outputToMonitorImage;
            app.outputImageHistogramAxisMax = colorHistogram(app, app.outputToMonitorImage, app.DestinationImageHistogramAxes);
        end

        function maxRGB = colorHistogram(~, rgb, parent)
            hR = histogram(parent, rgb(:,:,1), 256,'FaceColor','r','EdgeAlpha',0,'FaceAlpha',0.6);
            hold(parent, "on");
            hG = histogram(parent, rgb(:,:,2), 256,'FaceColor','g','EdgeAlpha',0,'FaceAlpha',0.6);
            hB = histogram(parent, rgb(:,:,3), 256,'FaceColor','b','EdgeAlpha',0,'FaceAlpha',0.6);
            maxRGB = max(max(hR.Values), max(hG.Values));
            maxRGB = max(maxRGB, max(hB.Values));
        end

        function keepFocus(app)
            % this is a hack to prevent the app from loosing focus
            figure(app.ImageDeltaEVisualizationUIFigure);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.ImageDeltaEVisualizationUIFigure, "center");
            % read in an sRGB profile to be the 'display' profile default.
            try
                app.sRGBProfile = iccread("profiles/sRGB Gamma22.icc");
                app.displayProfile = app.sRGBProfile;   % default for display
            catch
                app.DisplayProfileButton.Text = "No display colorspace. Pick one.";
                disp("could not read default sRGB color space. Sort it out");
            end
            app.DisplayProfileButton.Text = app.displayProfile.Description.String + " (disp)";
            set(app.DeltaEUIAxes, 'xtick', [], 'ytick', []);

            % set the to image displays to blank to hide the placeholder
            app.SourceImageDisplay.ImageSource = ones(64,64,3,'uint8')*255;
            app.DestinationImageDisplay.ImageSource = ones(64,64,3,'uint8')*255;

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: SourceImageButton
        function SourceImageButtonPushed(app, event)
            [file, path] = uigetfile( ...
                {'*.tif;*.tiff;*.jpg;*.jpeg;*.png', 'Image files'}, 'Select an image File');
            drawnow;
            app.keepFocus;
            cla(app.DeltaEUIAxes, "reset");

            imagePath = [path file];
            app.sourceImage = imread(imagePath);
            imshow(app.sourceImage, Parent=app.DeltaEUIAxes);
            axis(app.DeltaEUIAxes, "off");
            % push the image to the source/destination views so the user
            % has something to look at
            app.SourceImageDisplay.ImageSource = app.sourceImage;
            app.DestinationImageDisplay.ImageSource = app.sourceImage;
            app.SourceImageButton.Text = file;
            % now get the colorprofile for the image
            try
                % see if the image has an embedded profile (matalb is not
                % exhaustive about this, but try anyway.
                app.InputProfile = iccread(app.ImagePath);
                if app.destinationProfile.Header.ColorSpace ~= "RGB"
                    % we only handle RGB images no CMYB
                    msgbox("Cannot handle CMYG profiles.");
                    return;
                end
                app.SourceProfileButton.Text = app.sourceProfile.Description.String;
                app.SourceLabel.Text = app.sourceProfile.Description.String;
                refreshInputMappedImage(app);
            catch
                % no profile available.
                app.sourceProfile = false;
                app.SourceProfileButton.Text = "Input profile required.";
                app.SourceProfileButton.BackgroundColor = [0.93,0.69,0.13];
            end
        end

        % Button pushed function: DisplayProfileButton
        function DisplayProfileButtonButtonPushed(app, event)
            % the default display profile is sRGB.
            % This allows the user to override this with a selected monitor
            % profile
            [fn, pn] = uigetfile( ...
                {'*.icc;*.icm', ...
                'profile'}, ...
                'Select input image profile.', iccroot);
            oldpointer = get(app.ImageDeltaEVisualizationUIFigure, 'pointer');
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', 'watch');
            % drawnow;
            app.keepFocus;
            figure(app.ImageDeltaEVisualizationUIFigure);
            profilePath = [pn fn];
            try
                app.displayProfile = iccread(profilePath);
                if app.displayProfile.Header.ColorSpace ~= "RGB"
                    % we only handle RGB images no CMYB
                    msgbox("Cannot handle CMYG profiles.");
                    return;
                end
                app.DisplayProfileButton.Text = strcat(app.displayProfile.Description.String, " (disp)");
                app.DisplayProfileButton.BackgroundColor = [0.96,0.96,0.96];
                app.refreshInputMappedImage();
                app.refreshOutputMappedImage();
            catch ME
                disp(["error reading profile: ", ME.message]);
            end
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', oldpointer);
        end

        % Button pushed function: SourceProfileButton
        function SourceProfileButtonPushed(app, event)
            % Gather an input profile for the image
            [fn, pn] = uigetfile({'*.icc; *.icm', 'profile'},'Select input image profile.', iccroot);
            app.keepFocus;
            oldpointer = get(app.ImageDeltaEVisualizationUIFigure, 'pointer');
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', 'watch');
            % drawnow;
            % figure(app.ImageDeltaEVisualizationUIFigure);
            profilePath = [pn fn];
            try
                app.sourceProfile = iccread(profilePath);
                if app.sourceProfile.Header.ColorSpace ~= "RGB"
                    % we only handle RGB images no CMYB
                    msgbox("Cannot handle CMYG profiles.");
                    app.sourceProfileFlag = false;
                    return;
                end
                app.sourceProfileFlag = true;
                app.SourceProfileButton.Text = app.sourceProfile.Description.String;
                app.SourceLabel.Text = app.sourceProfile.Description.String;
                app.SourceProfileButton.BackgroundColor = [0.96,0.96,0.96];
                app.refreshInputMappedImage();
            catch ME
                disp(["error reading profile: ", ME.message]);
            end
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', oldpointer);
        end

        % Button pushed function: DestinationProfileButton
        function DestinationProfileButtonPushed(app, event)
            [fn, pn] = uigetfile({'*.icc; *.icm', 'profile'},'Select Destination profile.', iccroot);
            % drawnow;
            % figure(app.ImageDeltaEVisualizationUIFigure);
            profilePath = [pn fn];
            app.keepFocus;
            oldpointer = get(app.ImageDeltaEVisualizationUIFigure, 'pointer');
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', 'watch');
            try
                app.destinationProfile = iccread(profilePath);
                if app.destinationProfile.Header.ColorSpace ~= "RGB"
                    % we only handle RGB images no CMYB
                    msgbox("Cannot handle CMYG profiles.");
                    app.destinationProfileFlag = false;
                    return;
                end
                app.destinationProfileFlag = true;
                app.DestinationProfileButton.Text = app.destinationProfile.Description.String;
                app.DestinationLabel.Text = app.destinationProfile.Description.String;
                app.refreshOutputMappedImage();
            catch
                app.destinationProfileFlag = false;
                app.DestinationProfileButton.Text = "Output profile required.";
                app.DestinationProfileButton.BackgroundColor = [0.93,0.69,0.13];
            end
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', oldpointer);
        end

        % Button pushed function: DeltaEButton
        function DeltaEButtonPushed(app, event)
            % calcluate the deltaE and also the output profiled image and
            % display target 1 (input)
            oldpointer = get(app.ImageDeltaEVisualizationUIFigure, 'pointer');
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', 'watch');
            try
                app.inputCTransform = makecform("mattrc", app.sourceProfile, Direction="forward");
            catch
                try
                    app.inputCTransform = makecform("clut", app.sourceProfile,"AToB0");
                catch
                    disp("could not perform input profile forward transform");
                    return;
                end
            end

            target1 = applycform(app.sourceImage,app.inputCTransform);
            target1 = double(target1);
            switch app.sourceProfile.Header.ConnectionSpace
                case "XYZ"
                    target1 = xyz2lab(target1);
                case "Lab"
                otherwise
                    disp(["unknown pcs ... " pcs]);
            end

            % target 2 (output)
            try
                app.outputCTranform = makecform("mattrc", app.destinationProfile, Direction="forward");
            catch
                try
                    app.outputCTranform = makecform("clut", app.destinationProfile,"AToB0");
                catch
                    disp("could not perform outpuot profile forward transform");
                    return;
                end
            end

            target2 = applycform(app.sourceImage,app.outputCTranform);
            target2 = double(target2);

            switch app.destinationProfile.Header.ConnectionSpace
                case "XYZ"
                    target2 = double(xyz2lab(target2));
                case "Lab"
                    disp("Connection space = Lab for target2");
                otherwise
                    disp(["unknown pcs ... " pcs]);
            end
            app.dEImage = imcolordiff(target1, target2, isInputLab=true, Standard="CIEDE2000");
            imshow(app.dEImage, [], "Parent", app.DeltaEUIAxes);

            grid(app.DeltaEUIAxes, "off");
            colormap(app.DeltaEUIAxes, parula);
            colorbar(app.DeltaEUIAxes);
            set(app.ImageDeltaEVisualizationUIFigure, 'pointer', oldpointer);

            % normalize the two image histograms
            app.SourceImageHistogramAxes.YLim = [0,app.inputImageHistogramAxisMax];
            app.DestinationImageHistogramAxes.YLim = [0, app.outputImageHistogramAxisMax];
            % and do the histogram
            histogram(app.DeltaEHistogramUIAxes, app.dEImage);
   
        end

        % Value changed function: RenderingIntentDropDown
        function RenderingIntentDropDownValueChanged(app, event)
            value = app.RenderingIntentDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageDeltaEVisualizationUIFigure = uifigure('Visible', 'off');
            app.ImageDeltaEVisualizationUIFigure.Name = 'Image Delta E Visualization';
            app.ImageDeltaEVisualizationUIFigure.Tag = 'ColorToolboxTag';
            app.GridLayout = uigridlayout(app.ImageDeltaEVisualizationUIFigure);
            app.DestinationImageHistogramAxes = uiaxes(app.GridLayout);
            title(app.DestinationImageHistogramAxes, 'Destination RGB Histogram')
            xlabel(app.DestinationImageHistogramAxes, 'X')
            ylabel(app.DestinationImageHistogramAxes, 'Y')
            zlabel(app.DestinationImageHistogramAxes, 'Z')
            app.SourceImageHistogramAxes = uiaxes(app.GridLayout);
            title(app.SourceImageHistogramAxes, 'Source RGB Histogram')
            xlabel(app.SourceImageHistogramAxes, 'X')
            ylabel(app.SourceImageHistogramAxes, 'Y')
            zlabel(app.SourceImageHistogramAxes, 'Z')
            app.DeltaEHistogramUIAxes = uiaxes(app.GridLayout);
            title(app.DeltaEHistogramUIAxes, 'DeltaE Histogram')
            xlabel(app.DeltaEHistogramUIAxes, 'DE')
            ylabel(app.DeltaEHistogramUIAxes, 'Count')
            zlabel(app.DeltaEHistogramUIAxes, 'Z')
            app.DeltaEUIAxes = uiaxes(app.GridLayout);
            app.DeltaEUIAxes.XLim = [0 1];
            app.DeltaEUIAxes.XTick = [];
            app.DeltaEUIAxes.YTick = [];
            app.SourceImageButton = uibutton(app.GridLayout, 'push');
            app.SourceImageButton.ButtonPushedFcn = createCallbackFcn(app, @SourceImageButtonPushed, true);
            app.SourceImageButton.Text = 'Image';
            app.DestinationProfileButton = uibutton(app.GridLayout, 'push');
            app.DestinationProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DestinationProfileButtonPushed, true);
            app.DestinationProfileButton.Text = 'Destination Profile';
            app.SourceProfileButton = uibutton(app.GridLayout, 'push');
            app.SourceProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SourceProfileButtonPushed, true);
            app.SourceProfileButton.Text = 'Source profile';
            app.SourceImageandDeltaELabel = uilabel(app.GridLayout);
            app.SourceImageandDeltaELabel.Text = 'Source Image and DeltaE';
            app.SourceLabel = uilabel(app.GridLayout);
            app.SourceLabel.Text = 'Source';
            app.DestinationLabel = uilabel(app.GridLayout);
            app.DestinationLabel.Text = 'Destination';
            app.SourceImageDisplay = uiimage(app.GridLayout);
            app.DestinationImageDisplay = uiimage(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ExitButton = uibutton(app.GridLayout2, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.DeltaEButton = uibutton(app.GridLayout2, 'push');
            app.DeltaEButton.ButtonPushedFcn = createCallbackFcn(app, @DeltaEButtonPushed, true);
            app.DeltaEButton.Text = 'Delta E';
            app.DisplayProfileButton = uibutton(app.GridLayout2, 'push');
            app.DisplayProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayProfileButtonButtonPushed, true);
            app.DisplayProfileButton.Text = 'Display Profile (sRGB)';
            app.RenderingIntentDropDownLabel = uilabel(app.GridLayout2);
            app.RenderingIntentDropDownLabel.Text = 'Rendering Intent';
            app.RenderingIntentDropDown = uidropdown(app.GridLayout2);
            app.RenderingIntentDropDown.Items = {'Perceptual', 'Absolute', 'Relative', 'Saturation'};
            app.RenderingIntentDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingIntentDropDownValueChanged, true);
            app.RenderingIntentDropDown.Value = 'Perceptual';
            % Show the figure after all components are created
            app.ImageDeltaEVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageDeltaE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDeltaEVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDeltaEVisualizationUIFigure)
        end
    end
end